classdef ObjD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup2                    matlab.ui.container.TabGroup
        MATLABTab                    matlab.ui.container.Tab
        LoadTestDataPanel            matlab.ui.container.Panel
        TableDataButton_2            matlab.ui.control.Button
        LoadTrainDataPanel           matlab.ui.container.Panel
        TableDataButton              matlab.ui.control.Button
        JsonTab                      matlab.ui.container.Tab
        LoadTestDataPanel_2          matlab.ui.container.Panel
        JsonFileButton_2             matlab.ui.control.Button
        LoadTrainDataPanel_2         matlab.ui.container.Panel
        JsonFileButton               matlab.ui.control.Button
        XMLTab                       matlab.ui.container.Tab
        LoadTestDataPanel_3          matlab.ui.container.Panel
        ImageFileButton_2            matlab.ui.control.Button
        XMLFileButton_2              matlab.ui.control.Button
        LoadTrainDataPanel_3         matlab.ui.container.Panel
        ImageFileButton              matlab.ui.control.Button
        XMLFileButton                matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        YOLOv4Tab                    matlab.ui.container.Tab
        YOLOv3Tab                    matlab.ui.container.Tab
        YOLOv2Tab                    matlab.ui.container.Tab
        InputSizenumclassPanel_3     matlab.ui.container.Panel
        AnchorBoxEditField           matlab.ui.control.NumericEditField
        AnchorBoxEditFieldLabel      matlab.ui.control.Label
        ClassEditField_3             matlab.ui.control.NumericEditField
        ClassEditField_3Label        matlab.ui.control.Label
        WeightEditField_6            matlab.ui.control.NumericEditField
        WeightEditField_6Label       matlab.ui.control.Label
        HightEditField_6             matlab.ui.control.NumericEditField
        HightEditField_6Label        matlab.ui.control.Label
        SplitDataAgumentDataPanel_3  matlab.ui.container.Panel
        AgumentDataCheckBox_3        matlab.ui.control.CheckBox
        TrainEditField_3             matlab.ui.control.NumericEditField
        TrainEditField_3Label        matlab.ui.control.Label
        OptionTrainPanel_5           matlab.ui.container.Panel
        YOLOv2TestButton             matlab.ui.control.Button
        YOLOv2TrainButton            matlab.ui.control.Button
        OptionButton_5               matlab.ui.control.Button
        BaseNetworkButtonGroup_3     matlab.ui.container.ButtonGroup
        DropDown_2                   matlab.ui.control.DropDown
        SSDTab                       matlab.ui.container.Tab
        InputSizenumclassPanel       matlab.ui.container.Panel
        ClassEditField               matlab.ui.control.NumericEditField
        ClassEditFieldLabel          matlab.ui.control.Label
        WeightEditField_4            matlab.ui.control.NumericEditField
        WeightEditField_4Label       matlab.ui.control.Label
        HightEditField_4             matlab.ui.control.NumericEditField
        HightEditField_4Label        matlab.ui.control.Label
        SplitDataAgumentDataPanel    matlab.ui.container.Panel
        AgumentDataCheckBox          matlab.ui.control.CheckBox
        TrainEditField               matlab.ui.control.NumericEditField
        TrainEditFieldLabel          matlab.ui.control.Label
        OptionTrainPanel_2           matlab.ui.container.Panel
        TestButton                   matlab.ui.control.Button
        SSDTrainButton               matlab.ui.control.Button
        OptionButton                 matlab.ui.control.Button
        BaseNetworkButtonGroup       matlab.ui.container.ButtonGroup
        Resnet101Button              matlab.ui.control.RadioButton
        Resnet50Button               matlab.ui.control.RadioButton
        VGG16Button                  matlab.ui.control.RadioButton
        FasterRCNNTab                matlab.ui.container.Tab
        InputSizenumclassPanel_2     matlab.ui.container.Panel
        ClassEditField_2             matlab.ui.control.NumericEditField
        ClassEditField_2Label        matlab.ui.control.Label
        WeightEditField_5            matlab.ui.control.NumericEditField
        WeightEditField_5Label       matlab.ui.control.Label
        HightEditField_5             matlab.ui.control.NumericEditField
        HightEditField_5Label        matlab.ui.control.Label
        SplitDataAgumentDataPanel_2  matlab.ui.container.Panel
        AgumentDataCheckBox_2        matlab.ui.control.CheckBox
        TrainEditField_2             matlab.ui.control.NumericEditField
        TrainEditField_2Label        matlab.ui.control.Label
        OptionTrainPanel_4           matlab.ui.container.Panel
        FasterRCNNTestButton         matlab.ui.control.Button
        FasterRCNNTrainButton        matlab.ui.control.Button
        OptionButton_4               matlab.ui.control.Button
        BaseNetworkButtonGroup_2     matlab.ui.container.ButtonGroup
        DropDown                     matlab.ui.control.DropDown
        Panel                        matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % Json Read
        % 讀取訓練與測試檔案路徑
        TrainData_path
        TrainFile_path

        TestData_path
        TestFile_path
        % 建立gTruth Labeler
        TraingTruth_labeler
        TestgTruth_labeler
        % 建立完整資料集
        TrainImg
        TestImg

        % XML Read
        TrainXML_path
        TestXML_path

        % MATLAB Table Read
        answer1
        answer2

        %20220816--------------------------------------------------------
        % Label
        T_gTruth

        % 訓練測試資料
        trainingDataTbl
        testDataTbl

        % Datastore
        imdsTrain
        imdsTest
        bldsTrain
        bldsTest

        % net
        net
        featureLayer

        trainingData
        testData

    end

    methods (Access = public)

        function B = augmentData(app,A)
            B = cell(size(A));

            I = A{1};
            sz = size(I);
            if numel(sz)==3 && sz(3) == 3
                I = jitterColorHSV(I,...
                    'Contrast',0.2,...
                    'Hue',0,...
                    'Saturation',0.1,...
                    'Brightness',0.2);
            end

            % Randomly flip and scale image.
            tform = randomAffine2d('XReflection',true,'Scale',[1 1.1]);
            rout = affineOutputView(sz,tform,'BoundsStyle','CenterOutput');
            B{1} = imwarp(I,tform,'OutputView',rout);

            % Sanitize box data, if needed.
            A{2} = app.helperSanitizeBoxes(A{2}, sz);

            % Apply same transform to boxes.
            [B{2},indices] = bboxwarp(A{2},tform,rout,'OverlapThreshold',0.25);
            B{3} = A{3}(indices);

            % Return original data only when all boxes are removed by warping.
            if isempty(indices)
                B = A;
            end
        end

        function data = SSDpreprocessData(app,data,targetSize)
            % Resize image and bounding boxes to the targetSize.
            sz = size(data{1},[1 2]);
            scale = targetSize(1:2)./sz;
            data{1} = imresize(data{1},targetSize(1:2));

            % Sanitize box data, if needed.
            data{2} = app.helperSanitizeBoxes(data{2},sz);

            % Resize boxes to new image size.
            data{2} = bboxresize(data{2},scale);
        end

        function boxes = helperSanitizeBoxes(app,boxes, ~)
            persistent hasInvalidBoxes
            valid = all(boxes > 0, 2);
            if any(valid)
                if ~all(valid) && isempty(hasInvalidBoxes)
                    % Issue one-time warning about removing invalid boxes.
                    hasInvalidBoxes = true;
                    warning('Removing ground truth bouding box data with values <= 0.')
                end
                boxes = boxes(valid,:);
            end

        end
        function boxes = roundFractionalBoxes(app,boxes, imageSize)
            % If fractional data is present, issue one-time warning and round data and
            % clip to image size.
            persistent hasIssuedWarning

            allPixelCoordinates = isequal(floor(boxes), boxes);
            if ~allPixelCoordinates

                if isempty(hasIssuedWarning)
                    hasIssuedWarning = true;
                    warning('Rounding ground truth bounding box data to integer values.')
                end

                boxes = round(boxes);
                boxes(:,1:2) = max(boxes(:,1:2), 1);
                boxes(:,3:4) = min(boxes(:,3:4), imageSize([2 1]));
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: JsonFileButton
        function JsonFileButtonPushed(app, event)
            % Json
            % 讀檔
            [app.TrainFile_path,app.TrainData_path] = uigetfile('*.json');
            % 建立字串
            filename = [app.TrainData_path,app.TrainFile_path];
            % 解碼整理
            strData = fileread(filename);
            DecodeData = jsondecode(strData);

            idx = findgroups([DecodeData.annotations.image_id]);
            T_bbox = splitapply(@(x){x},[DecodeData.annotations.bbox],idx)';
            T_file = {DecodeData.images.file_name}';
            % arrayfun(@(x)getfield(x,'file_name'),DecodeData.images,'UniformOutput',false);
            app.TraingTruth_labeler = table(T_file,T_bbox);

            % 建立字串
            app.TrainImg = strcat(app.TrainData_path,string(app.TraingTruth_labeler.T_file));

            % 建Image DataStore
            % Image
            app.imdsTrain = imageDatastore(app.TrainImg);
            % Bbox
            app.bldsTrain = boxLabelDatastore(app.TraingTruth_labeler(:, 2:end));

            % 合併DataStore
            app.trainingData = combine(app.imdsTrain, app.bldsTrain);
        end

        % Button pushed function: JsonFileButton_2
        function JsonFileButton_2Pushed(app, event)
            % Json
            % 讀檔
            [app.TestFile_path,app.TestData_path] = uigetfile('*.json');
            % 建立字串
            filename2 = [app.TestData_path,app.TestFile_path];
            % 解碼整理
            strData2 = fileread(filename2);
            DecodeData2 = jsondecode(strData2);
            %
            idx2 = findgroups([DecodeData2.annotations.image_id]);
            T_bbox2 = splitapply(@(x){x},[DecodeData2.annotations.bbox],idx2)';
            T_file2 = {DecodeData2.images.file_name}';
            % arrayfun(@(x)getfield(x,'file_name'),DecodeData.images,'UniformOutput',false);
            app.TestgTruth_labeler = table(T_file2,T_bbox2);

            % 建立字串
            app.TestImg = strcat(app.TestData_path,string(app.TestgTruth_labeler.T_file2));

            % 建Image DataStore
            % Image
            app.imdsTest = imageDatastore(app.TestImg);
            % bbox
            app.bldsTest = boxLabelDatastore(app.TestgTruth_labeler(:, 2:end));

            % 合併DataStore
            app.testData = combine(app.imdsTest, app.bldsTest);
        end

        % Button pushed function: XMLFileButton
        function XMLFileButtonPushed(app, event)
            % XML 訓練
            % 載入XML路徑資料夾
            app.TrainXML_path = uigetdir();
            ds = fileDatastore(app.TrainXML_path,'ReadFcn',@readFcn);
            T_bbox= readall(ds);
            ds2 = fileDatastore(app.TrainXML_path,'ReadFcn',@readFcn2);
            T_file= readall(ds2);

            app.TraingTruth_labeler = table(T_file,T_bbox);

        end

        % Button pushed function: ImageFileButton
        function ImageFileButtonPushed(app, event)
            % XML 訓練
            % 載入Image路徑
            app.TrainData_path = uigetdir();
            % 做整併
            app.TrainData_path = [app.TrainData_path,'\'];
            app.TrainImg = strcat(app.TrainData_path,string(app.TraingTruth_labeler.T_file));

            % 建Image DataStore
            % Image
            app.imdsTrain = imageDatastore(app.TrainImg);
            % Bbox
            app.bldsTrain = boxLabelDatastore(app.TraingTruth_labeler(:, 2:end));

            % 合併DataStore
            app.trainingData = combine(app.imdsTrain, app.bldsTrain);

        end

        % Button pushed function: XMLFileButton_2
        function XMLFileButton_2Pushed(app, event)
            % XML 測試
            % 載入XML路徑資料夾
            app.TestXML_path = uigetdir();
            ds = fileDatastore(app.TestXML_path,'ReadFcn',@readFcn);
            T_bbox2= readall(ds);
            ds2 = fileDatastore(app.TestXML_path,'ReadFcn',@readFcn2);
            T_file2= readall(ds2);

            app.TestgTruth_labeler = table(T_file2,T_bbox2);
        end

        % Button pushed function: ImageFileButton_2
        function ImageFileButton_2Pushed(app, event)
            % XML 測試
            % 載入Image路徑
            app.TestData_path = uigetdir();
            % 做整併
            app.TestData_path = [app.TestData_path,'\'];
            app.TestImg = strcat(app.TestData_path,string(app.TestgTruth_labeler.T_file));

            % 建Image DataStore
            % Image
            app.imdsTest = imageDatastore(app.TestImg);
            % bbox
            app.bldsTest = boxLabelDatastore(app.TestgTruth_labeler(:, 2:end));

            % 合併DataStore
            app.testData = combine(app.imdsTest, app.bldsTest);

        end

        % Button pushed function: TableDataButton
        function TableDataButtonPushed(app, event)
            % Table格式 訓練資料
            app.answer1 = questdlg(sprintf("1 : 選擇標記檔案(File Table格式) 檔名需為T_gTruth \n2 : 選擇標記變數(workspace)"),'Label Source','1','2','1');

            if strcmp(app.answer1,'1')
                [app.TrainFile_path,app.TrainData_path] = uigetfile('*.mat');
                try
                    % 取檔案
                    filename = [app.TrainData_path,app.TrainFile_path];
                    load(filename)
                    app.T_gTruth = T_gTruth;
                catch
                    errordlg('No File is selected');
                end
                message = {'成功載入'};
                uialert(app.UIFigure,message,'Success','Icon','success');

            elseif strcmp(app.answer1,'2')
                app.answer2 = inputdlg(sprintf("Enter the name of variable in workspace to represent your label source:\nThe variable must be Tabel"),'Workspace Variable for Label Source', [1 90]);
                try
                    T_gTruth = evalin('base',string(app.answer2));
                    % 資料分割(DataSet Split)
                    rng(0)
                    shuffledIndices = randperm(height(T_gTruth));
                    idx = floor(0.8 * height(T_gTruth));

                    trainingIdx = 1:idx;
                    app.trainingDataTbl = T_gTruth(shuffledIndices(trainingIdx),:);

                    testIdx = trainingIdx(end)+1 : length(shuffledIndices);
                    app.testDataTbl = T_gTruth(shuffledIndices(testIdx),:);

                    % 建立資料庫
                    % Train
                    app.imdsTrain = imageDatastore(app.trainingDataTbl.imageFilename);
                    app.bldsTrain = boxLabelDatastore(app.trainingDataTbl(:,2:end));

                    % Test
                    app.imdsTest = imageDatastore(app.testDataTbl.imageFilename);
                    app.bldsTest = boxLabelDatastore(app.testDataTbl(:,2:end));

                    % 整併資料庫
                    app.trainingData = combine(app.imdsTrain,app.bldsTrain);
                    app.testData = combine(app.imdsTest,app.bldsTest);

                catch ME
                    errordlg(ME.message,ME.identifier);
                end
                message = {'成功載入'};
                uialert(app.UIFigure,message,'Success','Icon','success');
            end


        end

        % Button pushed function: TableDataButton_2
        function TableDataButton_2Pushed(app, event)
            % Table格式 測試資料
            app.answer1 = questdlg(sprintf("1 : 選擇標記檔案(File Table格式) 檔名需為gTruth \n2 : 選擇標記變數 Table格式(workspace)"),'Label Source','1','2','1');

            if strcmp(app.answer1,'1')
                [app.TestFile_path,app.TestData_path] = uigetfile('*.mat');
                try
                    %取檔案
                    filename = [app.TestData_path,app.TestFile_path];
                    load(filename)
                    % Image
                    app.imdsTest = imageDatastore(gTruth.imageFilename);
                    % Bbox
                    app.bldsTest = boxLabelDatastore(gTruth(:,2:end));

                    % 合併DataStore
                    app.testData = combine(app.imdsTest, app.bldsTest);

                catch
                    errordlg('No File is selected');
                end
                message = {'成功載入'};
                uialert(app.UIFigure,message,'Success','Icon','success');

            elseif strcmp(app.answer1,'2')
                app.answer2 = inputdlg(sprintf("Enter the name of variable in workspace to represent your label source:\nThe variable must be Tabel"),'Workspace Variable for Label Source', [1 90]);
                try
                    gTruth = evalin('base',string(app.answer2));
                    % Image
                    app.imdsTrain = imageDatastore(gTruth.imageFilename);
                    % Bbox
                    app.bldsTest = boxLabelDatastore(gTruth(:,2:end));

                    % 合併DataStore
                    app.testData = combine(app.imdsTrain, app.bldsTest);

                catch ME
                    errordlg(ME.message,ME.identifier);
                end
                message = {'成功載入'};
                uialert(app.UIFigure,message,'Success','Icon','success');
            end
        end

        % Callback function
        function GroundTruthDataButtonPushed(app, event)

        end

        % Callback function
        function GroundTruthDataButton_2Pushed(app, event)

        end

        % Value changed function: TrainEditField
        function TrainEditFieldValueChanged(app, event)
            Trainvalue = app.TrainEditField.Value;
            Testvalue  = 100 - Trainvalue;
        end

        % Button pushed function: SSDTrainButton
        function SSDTrainButtonPushed(app, event)
             Trainspilt = app.TrainEditField.Value/100;
            % 資料分割(DataSet Split)
                rng(0)
                shuffledIndices = randperm(height(app.T_gTruth));
                idx = floor(Trainspilt* height(app.T_gTruth));

                trainingIdx = 1:idx;
                app.trainingDataTbl = app.T_gTruth(shuffledIndices(trainingIdx),:);

                testIdx = trainingIdx(end)+1 : length(shuffledIndices);
                app.testDataTbl = app.T_gTruth(shuffledIndices(testIdx),:);

                % 建立資料庫
                % Train
                app.imdsTrain = imageDatastore(app.trainingDataTbl.imageFilename);
                app.bldsTrain = boxLabelDatastore(app.trainingDataTbl(:,2:end));

                % Test
                app.imdsTest = imageDatastore(app.testDataTbl.imageFilename);
                app.bldsTest = boxLabelDatastore(app.testDataTbl(:,2:end));

                % 整併資料庫
                app.trainingData = combine(app.imdsTrain,app.bldsTrain);
                app.testData = combine(app.imdsTest,app.bldsTest);

            % 資料增量
            augmentedTrainingData = transform(app.trainingData,@app.augmentData);

            augmentedData = cell(4,1);
            for k = 1:4
                data = read(augmentedTrainingData);
                augmentedData{k} = insertShape(data{1},'Rectangle',data{2});
                reset(augmentedTrainingData);
            end
            %figure,montage(augmentedData,'BorderSize',10)

            % 建置網路架構 SSD + mobilenetv2
            inputsizeH = app.HightEditField_4.Value;
            inputsizeW = app.WeightEditField_4.Value;
            inputSize = [inputsizeH inputsizeW 3];
            numClasses = app.ClassEditField.Value;

            % 選擇網路架構
            if (app.Resnet101Button.Value == 1)
                net = 'resnet101';
            elseif(app.Resnet50Button.Value == 1)
                net = 'resnet50';
            elseif(app.VGG16Button.Value == 1)
                net = 'vgg16';
            else

            end
            lgraph = ssdLayers(inputSize, numClasses, net);

            % 流程前處理 資料Size校正
            if (app.AgumentDataCheckBox.Value ==1) %check agument

                preprocessedTrainingData = transform(augmentedTrainingData,@(data)app.SSDpreprocessData(data,inputSize));

                data = read(preprocessedTrainingData);

                I = data{1};
                bbox = data{2};
                annotatedImage = insertShape(I,'Rectangle',bbox);
                annotatedImage = imresize(annotatedImage,2);
                %figure,imshow(annotatedImage)

            else
                preprocessedTrainingData = transform(app.trainingData,@(data)app.SSDpreprocessData(data,inputSize));

                data = read(preprocessedTrainingData);

                I = data{1};
                bbox = data{2};
                annotatedImage = insertShape(I,'Rectangle',bbox);
                annotatedImage = imresize(annotatedImage,2);
            end

            % 資料參數
            options = trainingOptions('sgdm', ...
                'MiniBatchSize', 128, ....
                'InitialLearnRate',0.001, ...
                'LearnRateSchedule', 'piecewise', ...
                'LearnRateDropPeriod', 30, ...
                'LearnRateDropFactor', 0.8, ...
                'MaxEpochs', 20, ...
                'VerboseFrequency', 50, ...
                'CheckpointPath', tempdir, ...
                'Shuffle','every-epoch');

            % Train
            SSDdetector = trainSSDObjectDetector(preprocessedTrainingData,lgraph,options);


        end

        % Button pushed function: FasterRCNNTrainButton
        function FasterRCNNTrainButtonPushed(app, event)
            Trainspilt = app.TrainEditField_2.Value/100; %**
            % 資料分割(DataSet Split)
                rng(0)
                shuffledIndices = randperm(height(app.T_gTruth));
                idx = floor(Trainspilt* height(app.T_gTruth));

                trainingIdx = 1:idx;
                app.trainingDataTbl = app.T_gTruth(shuffledIndices(trainingIdx),:);

                testIdx = trainingIdx(end)+1 : length(shuffledIndices);
                app.testDataTbl = app.T_gTruth(shuffledIndices(testIdx),:);

                % 建立資料庫
                % Train
                app.imdsTrain = imageDatastore(app.trainingDataTbl.imageFilename);
                app.bldsTrain = boxLabelDatastore(app.trainingDataTbl(:,2:end));

                % Test
                app.imdsTest = imageDatastore(app.testDataTbl.imageFilename);
                app.bldsTest = boxLabelDatastore(app.testDataTbl(:,2:end));

                % 整併資料庫
                app.trainingData = combine(app.imdsTrain,app.bldsTrain);
                app.testData = combine(app.imdsTest,app.bldsTest);

                % 資料增量
                augmentedTrainingData = transform(app.trainingData,@app.augmentData);

                augmentedData = cell(4,1);
                for k = 1:4
                    data = read(augmentedTrainingData);
                    augmentedData{k} = insertShape(data{1},'Rectangle',data{2});
                    reset(augmentedTrainingData);
                end
                %figure,montage(augmentedData,'BorderSize',10)

                % 建置網路架構 SSD + mobilenetv2
                inputsizeH = app.HightEditField_5.Value;
                inputsizeW = app.WeightEditField_5.Value;
                inputSize = [inputsizeH inputsizeW 3];
                numClasses = app.ClassEditField_2.Value;

                % 選擇網路架構
                switch(str2num(app.DropDown.Value))
                    case 1
                        app.net = 'alexnet';
                        app.featureLayer = 'relu5';
                    case 2
                        app.net = 'vgg16';
                        app.featureLayer = 'relu5';
                    case 3
                        app.net = 'vgg19';
                        app.featureLayer = 'relu5';
                    case 4
                        app.net = 'resnet18';
                        app.featureLayer = 'res5b_relu';
                    case 5
                        app.net = 'resnet50';
                        app.featureLayer = 'activation_49_relu';
                    case 6
                        app.net = 'resnet101';
                        app.featureLayer = 'res5c_relu';
                    case 7
                        app.net = 'inceptionv3';
                        app.featureLayer = 'activation_94_relu';
                    case 8
                        app.net = 'googlenet';
                        app.featureLayer = 'inception_5b-relu_pool_proj';
                    case 9
                        app.net = 'inceptionresnetv2';
                        app.featureLayer = 'conv_7b_ac';
                    case 10
                        app.net = 'squeezenet';
                        app.featureLayer = 'out_relu';
                    case 11
                        app.net = 'mobilenetv2';
                        app.featureLayer ='out_relu';
                end

                anchorBoxes = [64,64; 128,128; 192,192];

                lgraph = fasterRCNNLayers(inputSize ,numClasses,anchorBoxes, ...
                    app.net,app.featureLayer);


                % 流程前處理 資料Size校正
                if (app.AgumentDataCheckBox_2.Value ==1) %check agument

                    preprocessedTrainingData = transform(augmentedTrainingData,@(data)app.SSDpreprocessData(data,inputSize));

                    data = read(preprocessedTrainingData);

                    I = data{1};
                    bbox = data{2};
                    annotatedImage = insertShape(I,'Rectangle',bbox);
                    annotatedImage = imresize(annotatedImage,2);
                    %figure,imshow(annotatedImage)

                else
                    preprocessedTrainingData = transform(app.trainingData,@(data)app.SSDpreprocessData(data,inputSize));

                    data = read(preprocessedTrainingData);

                    I = data{1};
                    bbox = data{2};
                    annotatedImage = insertShape(I,'Rectangle',bbox);
                    annotatedImage = imresize(annotatedImage,2);
                end
                % 資料參數
                options = trainingOptions('sgdm',...
                    'MaxEpochs',20,...
                    'MiniBatchSize',16,...
                    'InitialLearnRate',0.001,...
                    'CheckpointPath',tempdir,...
                    'ValidationData',app.testData);

                % 訓練
                [FasterRCNNdetector, info] = trainFasterRCNNObjectDetector(app.trainingData,lgraph,options, ...
                    'NegativeOverlapRange',[0 0.3], ...
                    'PositiveOverlapRange',[0.6 1]);

        end

        % Button pushed function: YOLOv2TrainButton
        function YOLOv2TrainButtonPushed(app, event)
                Trainspilt = app.TrainEditField_3.Value/100; %**
            % 資料分割(DataSet Split)
                rng(0)
                shuffledIndices = randperm(height(app.T_gTruth));
                idx = floor(Trainspilt* height(app.T_gTruth));

                trainingIdx = 1:idx;
                app.trainingDataTbl = app.T_gTruth(shuffledIndices(trainingIdx),:);

                testIdx = trainingIdx(end)+1 : length(shuffledIndices);
                app.testDataTbl = app.T_gTruth(shuffledIndices(testIdx),:);

                % 建立資料庫
                % Train
                app.imdsTrain = imageDatastore(app.trainingDataTbl.imageFilename);
                app.bldsTrain = boxLabelDatastore(app.trainingDataTbl(:,2:end));

                % Test
                app.imdsTest = imageDatastore(app.testDataTbl.imageFilename);
                app.bldsTest = boxLabelDatastore(app.testDataTbl(:,2:end));

                % 整併資料庫
                app.trainingData = combine(app.imdsTrain,app.bldsTrain);
                app.testData = combine(app.imdsTest,app.bldsTest);

                % 資料增量
                augmentedTrainingData = transform(app.trainingData,@app.augmentData);

                augmentedData = cell(4,1);
                for k = 1:4
                    data = read(augmentedTrainingData);
                    augmentedData{k} = insertShape(data{1},'Rectangle',data{2});
                    reset(augmentedTrainingData);
                end
                %figure,montage(augmentedData,'BorderSize',10)

                % 建置網路架構 YOLOv2
                inputsizeH = app.HightEditField_6.Value;
                inputsizeW = app.WeightEditField_6.Value;
                inputSize = [inputsizeH inputsizeW 3];
                numClasses = app.ClassEditField_3.Value;
                numAnchors = app.AnchorBoxEditField;
                trainingDataForEstimation = transform(app.trainingData,@(data)app.SSDpreprocessData(data,inputSize));
                [anchorBoxes, meanIoU] = estimateAnchorBoxes(trainingDataForEstimation, numAnchors);
                
                switch(str2num(app.DropDown_2.Value))
                    case 1
                        app.net = 'alexnet';
                        app.featureLayer = 'relu5';
                    case 2
                        app.net = 'vgg16';
                        app.featureLayer = 'relu5';
                    case 3
                        app.net = 'vgg19';
                        app.featureLayer = 'relu5';
                    case 4
                        app.net = 'resnet18';
                        app.featureLayer = 'res5b_relu';
                    case 5
                        app.net = 'resnet50';
                        app.featureLayer = 'activation_49_relu';
                    case 6
                        app.net = 'resnet101';
                        app.featureLayer = 'res5c_relu';
                    case 7
                        app.net = 'inceptionv3';
                        app.featureLayer = 'activation_94_relu';
                    case 8
                        app.net = 'googlenet';
                        app.featureLayer = 'inception_5b-relu_pool_proj';
                    case 9
                        app.net = 'inceptionresnetv2';
                        app.featureLayer = 'conv_7b_ac';
                    case 10
                        app.net = 'squeezenet';
                        app.featureLayer = 'out_relu';
                    case 11
                        app.net = 'mobilenetv2';
                        app.featureLayer ='out_relu';
                end

                lgraph = yolov2Layers(inputSize,numClasses,anchorBoxes,app.net,app.featureLayer);


                % 流程前處理 資料Size校正
                if (app.AgumentDataCheckBox_2.Value ==1) %check agument

                    preprocessedTrainingData = transform(augmentedTrainingData,@(data)app.SSDpreprocessData(data,inputSize));

                    data = read(preprocessedTrainingData);

                    I = data{1};
                    bbox = data{2};
                    annotatedImage = insertShape(I,'Rectangle',bbox);
                    annotatedImage = imresize(annotatedImage,2);
                    %figure,imshow(annotatedImage)

                else
                    preprocessedTrainingData = transform(app.trainingData,@(data)app.SSDpreprocessData(data,inputSize));

                    data = read(preprocessedTrainingData);

                    I = data{1};
                    bbox = data{2};
                    annotatedImage = insertShape(I,'Rectangle',bbox);
                    annotatedImage = imresize(annotatedImage,2);
                end

                % 資料參數
                options = trainingOptions('sgdm', ...
                    'MiniBatchSize', 32, ....
                    'InitialLearnRate',1e-3, ...
                    'LearnRateSchedule', 'piecewise', ...
                    'LearnRateDropPeriod', 30, ...
                    'LearnRateDropFactor', 0.8, ...
                    'MaxEpochs', 100, ...
                    'VerboseFrequency', 50, ...
                    'CheckpointPath', tempdir, ...
                    'Shuffle','every-epoch');

                %
                [Yolov2detector,info] = trainYOLOv2ObjectDetector(preprocessedTrainingData,lgraph,options);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.YOLOv4Tab = uitab(app.TabGroup);
            app.YOLOv4Tab.Title = 'YOLOv4';
            app.YOLOv3Tab = uitab(app.TabGroup);
            app.YOLOv3Tab.Title = 'YOLOv3';
            app.YOLOv2Tab = uitab(app.TabGroup);
            app.YOLOv2Tab.Title = 'YOLOv2';
            app.BaseNetworkButtonGroup_3 = uibuttongroup(app.YOLOv2Tab);
            app.BaseNetworkButtonGroup_3.Title = 'Base Network';
            app.DropDown_2 = uidropdown(app.BaseNetworkButtonGroup_3);
            app.DropDown_2.Items = {'Alexnet', 'VGG16', 'VGG19', 'Resnet18', 'Resnet50', 'Resnet101', 'Inceptionv3', 'Goolgenet', 'Inceptionresnetv2', 'squeezenet', 'mobilenetv2'};
            app.DropDown_2.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.DropDown_2.Value = '1';
            app.OptionTrainPanel_5 = uipanel(app.YOLOv2Tab);
            app.OptionTrainPanel_5.Title = 'Option & Train';
            app.OptionButton_5 = uibutton(app.OptionTrainPanel_5, 'push');
            app.OptionButton_5.Text = 'Option';
            app.YOLOv2TrainButton = uibutton(app.OptionTrainPanel_5, 'push');
            app.YOLOv2TrainButton.ButtonPushedFcn = createCallbackFcn(app, @YOLOv2TrainButtonPushed, true);
            app.YOLOv2TrainButton.Text = {'YOLOv2 '; 'Train'};
            app.YOLOv2TestButton = uibutton(app.OptionTrainPanel_5, 'push');
            app.YOLOv2TestButton.Text = {'YOLOv2'; 'Test'};
            app.SplitDataAgumentDataPanel_3 = uipanel(app.YOLOv2Tab);
            app.SplitDataAgumentDataPanel_3.Title = 'Split Data & Agument Data';
            app.TrainEditField_3Label = uilabel(app.SplitDataAgumentDataPanel_3);
            app.TrainEditField_3Label.Text = 'Train(%)';
            app.TrainEditField_3 = uieditfield(app.SplitDataAgumentDataPanel_3, 'numeric');
            app.TrainEditField_3.Value = 85;
            app.AgumentDataCheckBox_3 = uicheckbox(app.SplitDataAgumentDataPanel_3);
            app.AgumentDataCheckBox_3.Text = 'Agument Data';
            app.AgumentDataCheckBox_3.Value = true;
            app.InputSizenumclassPanel_3 = uipanel(app.YOLOv2Tab);
            app.InputSizenumclassPanel_3.Title = 'Input Size & num class';
            app.HightEditField_6Label = uilabel(app.InputSizenumclassPanel_3);
            app.HightEditField_6Label.Text = 'Hight';
            app.HightEditField_6 = uieditfield(app.InputSizenumclassPanel_3, 'numeric');
            app.HightEditField_6.Value = 224;
            app.WeightEditField_6Label = uilabel(app.InputSizenumclassPanel_3);
            app.WeightEditField_6Label.Text = 'Weight';
            app.WeightEditField_6 = uieditfield(app.InputSizenumclassPanel_3, 'numeric');
            app.WeightEditField_6.Value = 224;
            app.ClassEditField_3Label = uilabel(app.InputSizenumclassPanel_3);
            app.ClassEditField_3Label.Text = 'Class';
            app.ClassEditField_3 = uieditfield(app.InputSizenumclassPanel_3, 'numeric');
            app.ClassEditField_3.Value = 1;
            app.AnchorBoxEditFieldLabel = uilabel(app.InputSizenumclassPanel_3);
            app.AnchorBoxEditFieldLabel.Text = 'Anchor Box';
            app.AnchorBoxEditField = uieditfield(app.InputSizenumclassPanel_3, 'numeric');
            app.AnchorBoxEditField.Value = 6;
            app.SSDTab = uitab(app.TabGroup);
            app.SSDTab.Title = 'SSD';
            app.BaseNetworkButtonGroup = uibuttongroup(app.SSDTab);
            app.BaseNetworkButtonGroup.Title = 'Base Network';
            app.VGG16Button = uiradiobutton(app.BaseNetworkButtonGroup);
            app.VGG16Button.Text = 'VGG16';
            app.Resnet50Button = uiradiobutton(app.BaseNetworkButtonGroup);
            app.Resnet50Button.Text = 'Resnet50';
            app.Resnet50Button.Value = true;
            app.Resnet101Button = uiradiobutton(app.BaseNetworkButtonGroup);
            app.Resnet101Button.Text = 'Resnet101';
            app.OptionTrainPanel_2 = uipanel(app.SSDTab);
            app.OptionTrainPanel_2.Title = 'Option & Train';
            app.OptionButton = uibutton(app.OptionTrainPanel_2, 'push');
            app.OptionButton.Text = 'Option';
            app.SSDTrainButton = uibutton(app.OptionTrainPanel_2, 'push');
            app.SSDTrainButton.ButtonPushedFcn = createCallbackFcn(app, @SSDTrainButtonPushed, true);
            app.SSDTrainButton.Text = 'SSDTrain';
            app.TestButton = uibutton(app.OptionTrainPanel_2, 'push');
            app.TestButton.Text = 'Test';
            app.SplitDataAgumentDataPanel = uipanel(app.SSDTab);
            app.SplitDataAgumentDataPanel.Title = 'Split Data & Agument Data';
            app.TrainEditFieldLabel = uilabel(app.SplitDataAgumentDataPanel);
            app.TrainEditFieldLabel.Text = 'Train(%)';
            app.TrainEditField = uieditfield(app.SplitDataAgumentDataPanel, 'numeric');
            app.TrainEditField.ValueChangedFcn = createCallbackFcn(app, @TrainEditFieldValueChanged, true);
            app.TrainEditField.Value = 85;
            app.AgumentDataCheckBox = uicheckbox(app.SplitDataAgumentDataPanel);
            app.AgumentDataCheckBox.Text = 'Agument Data';
            app.AgumentDataCheckBox.Value = true;
            app.InputSizenumclassPanel = uipanel(app.SSDTab);
            app.InputSizenumclassPanel.Title = 'Input Size & num class';
            app.HightEditField_4Label = uilabel(app.InputSizenumclassPanel);
            app.HightEditField_4Label.Text = 'Hight';
            app.HightEditField_4 = uieditfield(app.InputSizenumclassPanel, 'numeric');
            app.HightEditField_4.Value = 300;
            app.WeightEditField_4Label = uilabel(app.InputSizenumclassPanel);
            app.WeightEditField_4Label.Text = 'Weight';
            app.WeightEditField_4 = uieditfield(app.InputSizenumclassPanel, 'numeric');
            app.WeightEditField_4.Value = 300;
            app.ClassEditFieldLabel = uilabel(app.InputSizenumclassPanel);
            app.ClassEditFieldLabel.Text = 'Class';
            app.ClassEditField = uieditfield(app.InputSizenumclassPanel, 'numeric');
            app.ClassEditField.Value = 1;
            app.FasterRCNNTab = uitab(app.TabGroup);
            app.FasterRCNNTab.Title = 'Faster RCNN';
            app.BaseNetworkButtonGroup_2 = uibuttongroup(app.FasterRCNNTab);
            app.BaseNetworkButtonGroup_2.Title = 'Base Network';
            app.DropDown = uidropdown(app.BaseNetworkButtonGroup_2);
            app.DropDown.Items = {'Alexnet', 'VGG16', 'VGG19', 'Resnet18', 'Resnet50', 'Resnet101', 'Inceptionv3', 'Goolgenet', 'Inceptionresnetv2', 'squeezenet', 'mobilenetv2'};
            app.DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.DropDown.Value = '1';
            app.OptionTrainPanel_4 = uipanel(app.FasterRCNNTab);
            app.OptionTrainPanel_4.Title = 'Option & Train';
            app.OptionButton_4 = uibutton(app.OptionTrainPanel_4, 'push');
            app.OptionButton_4.Text = 'Option';
            app.FasterRCNNTrainButton = uibutton(app.OptionTrainPanel_4, 'push');
            app.FasterRCNNTrainButton.ButtonPushedFcn = createCallbackFcn(app, @FasterRCNNTrainButtonPushed, true);
            app.FasterRCNNTrainButton.Text = {'Faster RCNN '; 'Train'};
            app.FasterRCNNTestButton = uibutton(app.OptionTrainPanel_4, 'push');
            app.FasterRCNNTestButton.Text = {'Faster RCNN'; 'Test'};
            app.SplitDataAgumentDataPanel_2 = uipanel(app.FasterRCNNTab);
            app.SplitDataAgumentDataPanel_2.Title = 'Split Data & Agument Data';
            app.TrainEditField_2Label = uilabel(app.SplitDataAgumentDataPanel_2);
            app.TrainEditField_2Label.Text = 'Train(%)';
            app.TrainEditField_2 = uieditfield(app.SplitDataAgumentDataPanel_2, 'numeric');
            app.TrainEditField_2.Value = 85;
            app.AgumentDataCheckBox_2 = uicheckbox(app.SplitDataAgumentDataPanel_2);
            app.AgumentDataCheckBox_2.Text = 'Agument Data';
            app.AgumentDataCheckBox_2.Value = true;
            app.InputSizenumclassPanel_2 = uipanel(app.FasterRCNNTab);
            app.InputSizenumclassPanel_2.Title = 'Input Size & num class';
            app.HightEditField_5Label = uilabel(app.InputSizenumclassPanel_2);
            app.HightEditField_5Label.Text = 'Hight';
            app.HightEditField_5 = uieditfield(app.InputSizenumclassPanel_2, 'numeric');
            app.HightEditField_5.Value = 224;
            app.WeightEditField_5Label = uilabel(app.InputSizenumclassPanel_2);
            app.WeightEditField_5Label.Text = 'Weight';
            app.WeightEditField_5 = uieditfield(app.InputSizenumclassPanel_2, 'numeric');
            app.WeightEditField_5.Value = 224;
            app.ClassEditField_2Label = uilabel(app.InputSizenumclassPanel_2);
            app.ClassEditField_2Label.Text = 'Class';
            app.ClassEditField_2 = uieditfield(app.InputSizenumclassPanel_2, 'numeric');
            app.ClassEditField_2.Value = 1;
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.MATLABTab = uitab(app.TabGroup2);
            app.MATLABTab.Title = 'MATLAB';
            app.LoadTrainDataPanel = uipanel(app.MATLABTab);
            app.LoadTrainDataPanel.Title = 'Load Train Data';
            app.TableDataButton = uibutton(app.LoadTrainDataPanel, 'push');
            app.TableDataButton.ButtonPushedFcn = createCallbackFcn(app, @TableDataButtonPushed, true);
            app.TableDataButton.Text = 'Table Data';
            app.LoadTestDataPanel = uipanel(app.MATLABTab);
            app.LoadTestDataPanel.Title = 'Load Test Data';
            app.TableDataButton_2 = uibutton(app.LoadTestDataPanel, 'push');
            app.TableDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @TableDataButton_2Pushed, true);
            app.TableDataButton_2.Text = 'Table Data';
            app.JsonTab = uitab(app.TabGroup2);
            app.JsonTab.Title = 'Json';
            app.LoadTrainDataPanel_2 = uipanel(app.JsonTab);
            app.LoadTrainDataPanel_2.Title = 'Load Train Data';
            app.JsonFileButton = uibutton(app.LoadTrainDataPanel_2, 'push');
            app.JsonFileButton.ButtonPushedFcn = createCallbackFcn(app, @JsonFileButtonPushed, true);
            app.JsonFileButton.Text = 'Json File';
            app.LoadTestDataPanel_2 = uipanel(app.JsonTab);
            app.LoadTestDataPanel_2.Title = 'Load Test Data';
            app.JsonFileButton_2 = uibutton(app.LoadTestDataPanel_2, 'push');
            app.JsonFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @JsonFileButton_2Pushed, true);
            app.JsonFileButton_2.Text = 'Json File';
            app.XMLTab = uitab(app.TabGroup2);
            app.XMLTab.Title = 'XML';
            app.LoadTrainDataPanel_3 = uipanel(app.XMLTab);
            app.LoadTrainDataPanel_3.Title = 'Load Train Data';
            app.XMLFileButton = uibutton(app.LoadTrainDataPanel_3, 'push');
            app.XMLFileButton.ButtonPushedFcn = createCallbackFcn(app, @XMLFileButtonPushed, true);
            app.XMLFileButton.Text = 'XML File';
            app.ImageFileButton = uibutton(app.LoadTrainDataPanel_3, 'push');
            app.ImageFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImageFileButtonPushed, true);
            app.ImageFileButton.Text = 'Image File';
            app.LoadTestDataPanel_3 = uipanel(app.XMLTab);
            app.LoadTestDataPanel_3.Title = 'Load Test Data';
            app.XMLFileButton_2 = uibutton(app.LoadTestDataPanel_3, 'push');
            app.XMLFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @XMLFileButton_2Pushed, true);
            app.XMLFileButton_2.Text = 'XML File';
            app.ImageFileButton_2 = uibutton(app.LoadTestDataPanel_3, 'push');
            app.ImageFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImageFileButton_2Pushed, true);
            app.ImageFileButton_2.Text = 'Image File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ObjD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end